classdef Max_population < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DoublingtimeminutesEditFieldLabel  matlab.ui.control.Label
        DoublingtimeminutesEditField   matlab.ui.control.NumericEditField
        NumberofrecombinasesEditFieldLabel  matlab.ui.control.Label
        NumberofrecombinasesEditField  matlab.ui.control.NumericEditField
        InitialpopulationSliderLabel   matlab.ui.control.Label
        InitialpopulationSlider        matlab.ui.control.Slider
        Image                          matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
        PlotButton                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

r1 = 0.9;
r2 = 0.85;
r3 = 0.8;
r4 = 0.78;
%t_recom = 15; %Approximate time of recombination in minutes
%p_0 = 1000; %initial population
%n = 4; %n is equals the number of recombinases
t_recom = app.DoublingtimeminutesEditField.Value;
p_0 = app.InitialpopulationSlider.Value;
n = app.NumberofrecombinasesEditField.Value;
%There will be 2^n -1 nodes/leaves in the tree
sequence = 1;
%Sacar acumulado de cuantas bacterias han existido
%Relacionar con temas de bioprocesos
%Ingenieria inversa, pov cientifico
for i = 1:2^n -1
    for j = 1:n
        if mod(i,2^j)==2^(j-1)
            s1 = "r";
            s2 = int2str(j);
            s = strcat(s1,s2);
            if strcmp(s,"r1")
                k = r1;
            elseif strcmp(s,"r2")
                k = r2;
            elseif strcmp(s,"r3")
                k = r3;
            else
                k = r4;
            end
            break
        end
    end
sequence = [sequence k];
end
sequence(1) = [];

PM = zeros(2^n);
PM(2^n,2^n) = 1;
for i = 1:2^n - 1
    PM(i,i) = 1-sequence(i); %Fill Probability Matrix
    PM(i,i+1) = sequence(i);
end
%PM=[1-r1 r1 0 0 0 0 0 0;
    %0 1-r2 r2 0 0 0 0 0;
    %0 0 1-r1 r1 0 0 0 0;
    %0 0 0 1-r3 r3 0 0 0;
    %0 0 0 0 1-r1 r1 0 0;
    %0 0 0 0 0 1-r2 r2 0;
    %0 0 0 0 0 0 1-r1 r1;
    %0 0 0 0 0 0   0   1];%Probability Matrix
vector = zeros(2^n,1);
vector(1,1) = 1;
f = transpose(PM)*vector;
prob = 0;
while f(end)<0.999
    f = transpose(PM)*f;
    prob = [prob f(end)];
end

rel_prob = prob(1);
current = 0;
for i=2:size(prob,2)
    current = prob(i)-prob(i-1);
    rel_prob = [rel_prob current];
end

for i=1:size(prob,2)
    actual = p_0(i) - round(p_0(i)*prob(i)); %floor
    p_0 = [p_0 2*actual];
end

time = 1:size(p_0,2);

time = (time*t_recom)-t_recom;


plot(app.UIAxes,time,p_0,'-o');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DoublingtimeminutesEditFieldLabel = uilabel(app.UIFigure);
            app.DoublingtimeminutesEditFieldLabel.Text = 'Doubling time(minutes)';
            app.DoublingtimeminutesEditField = uieditfield(app.UIFigure, 'numeric');
            app.DoublingtimeminutesEditField.Value = 15;
            app.NumberofrecombinasesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofrecombinasesEditFieldLabel.Text = 'Number of recombinases';
            app.NumberofrecombinasesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofrecombinasesEditField.Value = 3;
            app.InitialpopulationSliderLabel = uilabel(app.UIFigure);
            app.InitialpopulationSliderLabel.Text = 'Initial population';
            app.InitialpopulationSlider = uislider(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'iGEM_CR.jpg';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Population of bacteria over time')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Population')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Max_population

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end