classdef Promoter_Strength < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FluorescentProteinButtonGroup   matlab.ui.container.ButtonGroup
        GFPButton                       matlab.ui.control.RadioButton
        RFPButton                       matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        Time1EditFieldLabel             matlab.ui.control.Label
        Time1EditField                  matlab.ui.control.NumericEditField
        Time2EditFieldLabel             matlab.ui.control.Label
        Time2EditField                  matlab.ui.control.NumericEditField
        PromoterStrengthEditFieldLabel  matlab.ui.control.Label
        PromoterStrengthEditField       matlab.ui.control.NumericEditField
        TimeUnitButtonGroup             matlab.ui.container.ButtonGroup
        SecondsButton                   matlab.ui.control.RadioButton
        MinutesButton                   matlab.ui.control.RadioButton
        HoursButton                     matlab.ui.control.RadioButton
        Fluorescence1EditFieldLabel     matlab.ui.control.Label
        Fluorescence1EditField          matlab.ui.control.NumericEditField
        Fluorescence2EditFieldLabel     matlab.ui.control.Label
        Fluorescence2EditField          matlab.ui.control.NumericEditField
        MaximumfluorescenceEditFieldLabel  matlab.ui.control.Label
        MaximumfluorescenceEditField    matlab.ui.control.NumericEditField
        SolveButton                     matlab.ui.control.Button
        Time3EditFieldLabel             matlab.ui.control.Label
        Time3EditField                  matlab.ui.control.NumericEditField
        Fluorescence3EditFieldLabel     matlab.ui.control.Label
        Fluorescence3EditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Property;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
%promo(0,25000,1080,26500,4860,31000)
syms x y z
if app.GFPButton.Value
    alpha = 15;
    gamma_1 = 0.0022;
    gamma_2 = 0.00052;
else
    alpha = 0.072;
    gamma_1 = 0.0022;
    gamma_2 = 0.0012;
    
end


if app.SecondsButton.Value   
    eqn1 = -(alpha/(gamma_1*(gamma_2-gamma_1)))*x*exp(-gamma_1*app.Time1EditField.Value)+y*exp(-gamma_2*app.Time1EditField.Value)+(alpha/(gamma_1*gamma_2))*z  == app.Fluorescence1EditField.Value;
    eqn2 = -(alpha/(gamma_1*(gamma_2-gamma_1)))*x*exp(-gamma_1*app.Time2EditField.Value)+y*exp(-gamma_2*app.Time2EditField.Value)+(alpha/(gamma_1*gamma_2))*z  == app.Fluorescence2EditField.Value;
    eqn3 = -(alpha/(gamma_1*(gamma_2-gamma_1)))*x*exp(-gamma_1*app.Time3EditField.Value)+y*exp(-gamma_2*app.Time3EditField.Value)+(alpha/(gamma_1*gamma_2))*z  == app.Fluorescence3EditField.Value;
elseif app.MinutesButton.Value
    eqn1 = -(alpha/(gamma_1*(gamma_2-gamma_1)))*x*exp(-gamma_1*app.Time1EditField.Value*60)+y*exp(-gamma_2*app.Time1EditField.Value*60)+(alpha/(gamma_1*gamma_2))*z  == app.Fluorescence1EditField.Value;
    eqn2 = -(alpha/(gamma_1*(gamma_2-gamma_1)))*x*exp(-gamma_1*app.Time2EditField.Value*60)+y*exp(-gamma_2*app.Time2EditField.Value*60)+(alpha/(gamma_1*gamma_2))*z  == app.Fluorescence2EditField.Value;
    eqn3 = -(alpha/(gamma_1*(gamma_2-gamma_1)))*x*exp(-gamma_1*app.Time3EditField.Value*60)+y*exp(-gamma_2*app.Time3EditField.Value*60)+(alpha/(gamma_1*gamma_2))*z  == app.Fluorescence3EditField.Value;
else
    eqn1 = -(alpha/(gamma_1*(gamma_2-gamma_1)))*x*exp(-gamma_1*app.Time1EditField.Value*3600)+y*exp(-gamma_2*app.Time1EditField.Value*3600)+(alpha/(gamma_1*gamma_2))*z  == app.Fluorescence1EditField.Value;
    eqn2 = -(alpha/(gamma_1*(gamma_2-gamma_1)))*x*exp(-gamma_1*app.Time2EditField.Value*3600)+y*exp(-gamma_2*app.Time2EditField.Value*3600)+(alpha/(gamma_1*gamma_2))*z  == app.Fluorescence2EditField.Value;
    eqn3 = -(alpha/(gamma_1*(gamma_2-gamma_1)))*x*exp(-gamma_1*app.Time3EditField.Value*3600)+y*exp(-gamma_2*app.Time3EditField.Value*3600)+(alpha/(gamma_1*gamma_2))*z  == app.Fluorescence3EditField.Value;
end


sol = solve([eqn1, eqn2, eqn3], [x, y, z]);
c1 = sol.x;
c2 = sol.y;
k = sol.z;
fun = @(n) -(alpha./(gamma_1.*(gamma_2-gamma_1))).*c1.*exp(-gamma_1.*n)+c2.*exp(-gamma_2.*n)+(alpha/(gamma_1.*gamma_2)).*k ;
app.PromoterStrengthEditField.Value = double(k);
app.MaximumfluorescenceEditField.Value = double((alpha/(gamma_1*gamma_2))*k);


if app.GFPButton.Value
    fplot(app.UIAxes,fun,[0,10800],'g');
else
    fplot(app.UIAxes,fun,[0,10800],'r');
end

        end

        % Value changed function: Time1EditField
        function Time1EditFieldValueChanged(app, event)
            value = app.Time1EditField.Value;
            
        end

        % Value changed function: Fluorescence1EditField
        function Fluorescence1EditFieldValueChanged(app, event)
            value = app.Fluorescence1EditField.Value;
            
        end

        % Value changed function: Time2EditField
        function Time2EditFieldValueChanged(app, event)
            value = app.Time2EditField.Value;
           
        end

        % Value changed function: Fluorescence2EditField
        function Fluorescence2EditFieldValueChanged(app, event)
            value = app.Fluorescence2EditField.Value;
            
        end

        % Value changed function: PromoterStrengthEditField
        function PromoterStrengthEditFieldValueChanged(app, event)
            value = app.promo;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FluorescentProteinButtonGroup = uibuttongroup(app.UIFigure);
            app.FluorescentProteinButtonGroup.Title = 'Fluorescent Protein';
            app.GFPButton = uiradiobutton(app.FluorescentProteinButtonGroup);
            app.GFPButton.Text = 'GFP';
            app.GFPButton.Value = true;
            app.RFPButton = uiradiobutton(app.FluorescentProteinButtonGroup);
            app.RFPButton.Text = 'RFP';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Promoter activity')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Fluorescence')
            app.Time1EditFieldLabel = uilabel(app.UIFigure);
            app.Time1EditFieldLabel.Text = 'Time 1';
            app.Time1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Time1EditField.ValueChangedFcn = createCallbackFcn(app, @Time1EditFieldValueChanged, true);
            app.Time2EditFieldLabel = uilabel(app.UIFigure);
            app.Time2EditFieldLabel.Text = 'Time 2';
            app.Time2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Time2EditField.ValueChangedFcn = createCallbackFcn(app, @Time2EditFieldValueChanged, true);
            app.Time2EditField.Value = 1080;
            app.PromoterStrengthEditFieldLabel = uilabel(app.UIFigure);
            app.PromoterStrengthEditFieldLabel.Text = 'Promoter Strength';
            app.PromoterStrengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.PromoterStrengthEditField.ValueChangedFcn = createCallbackFcn(app, @PromoterStrengthEditFieldValueChanged, true);
            app.TimeUnitButtonGroup = uibuttongroup(app.UIFigure);
            app.TimeUnitButtonGroup.Title = 'Time Unit';
            app.SecondsButton = uiradiobutton(app.TimeUnitButtonGroup);
            app.SecondsButton.Text = 'Seconds';
            app.SecondsButton.Value = true;
            app.MinutesButton = uiradiobutton(app.TimeUnitButtonGroup);
            app.MinutesButton.Text = 'Minutes';
            app.HoursButton = uiradiobutton(app.TimeUnitButtonGroup);
            app.HoursButton.Text = 'Hours';
            app.Fluorescence1EditFieldLabel = uilabel(app.UIFigure);
            app.Fluorescence1EditFieldLabel.Text = 'Fluorescence 1';
            app.Fluorescence1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Fluorescence1EditField.ValueChangedFcn = createCallbackFcn(app, @Fluorescence1EditFieldValueChanged, true);
            app.Fluorescence1EditField.Value = 25000;
            app.Fluorescence2EditFieldLabel = uilabel(app.UIFigure);
            app.Fluorescence2EditFieldLabel.Text = 'Fluorescence 2';
            app.Fluorescence2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Fluorescence2EditField.ValueChangedFcn = createCallbackFcn(app, @Fluorescence2EditFieldValueChanged, true);
            app.Fluorescence2EditField.Value = 26500;
            app.MaximumfluorescenceEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumfluorescenceEditFieldLabel.Text = 'Maximum fluorescence';
            app.MaximumfluorescenceEditField = uieditfield(app.UIFigure, 'numeric');
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.Time3EditFieldLabel = uilabel(app.UIFigure);
            app.Time3EditFieldLabel.Text = 'Time 3';
            app.Time3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Time3EditField.Value = 4860;
            app.Fluorescence3EditFieldLabel = uilabel(app.UIFigure);
            app.Fluorescence3EditFieldLabel.Text = 'Fluorescence 3';
            app.Fluorescence3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Fluorescence3EditField.Value = 31000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Promoter_Strength

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end